classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LabelAdvertencia     matlab.ui.control.Label
        Image                matlab.ui.control.Image
        AdministradorButton  matlab.ui.control.Button
        UsuarioButton        matlab.ui.control.Button
        ID                   matlab.ui.control.EditField
        IDLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        writeAPIKey = "71KCD4RO8TVRPF5H";
        channelID = 3150731;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UsuarioButton
        function UsuarioButtonButtonPushed(app, event)
            valuer = app.ID.Value;
            writeAPIKey = "71KCD4RO8TVRPF5H";
            channelID = 3150731;

            puestosValidos = ["AA","AB","AC","BA"];

            if any(valuer == puestosValidos)
                app2(valuer)
            else
                app.LabelAdvertencia.Text = "Puesto de parqueo no disponible";
            end


        end

        % Value changed function: ID
        function IDValueChanged(app, event)
            value = app.ID.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IDLabel = uilabel(app.UIFigure);
            app.IDLabel.Text = 'Puesto de parqueo';
            app.ID = uieditfield(app.UIFigure, 'text');
            app.ID.ValueChangedFcn = createCallbackFcn(app, @IDValueChanged, true);
            app.UsuarioButton = uibutton(app.UIFigure, 'push');
            app.UsuarioButton.ButtonPushedFcn = createCallbackFcn(app, @UsuarioButtonButtonPushed, true);
            app.UsuarioButton.Text = 'Usuario';
            app.AdministradorButton = uibutton(app.UIFigure, 'push');
            app.AdministradorButton.Text = 'Administrador';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'smart', '2.png');
            app.LabelAdvertencia = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end