classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        BCLabel_4         matlab.ui.control.Label
        BCLabel_3         matlab.ui.control.Label
        BCLabel_2         matlab.ui.control.Label
        BCLabel           matlab.ui.control.Label
        BBLabel           matlab.ui.control.Label
        BALabel           matlab.ui.control.Label
        ACLabel           matlab.ui.control.Label
        ABLabel           matlab.ui.control.Label
        AALabel           matlab.ui.control.Label
        LabelBA           matlab.ui.control.Label
        LabelAC           matlab.ui.control.Label
        LabelAB           matlab.ui.control.Label
        Label2            matlab.ui.control.Label
        ActualizarButton  matlab.ui.control.Button
        LabelAA           matlab.ui.control.Label
        CC                matlab.ui.control.Lamp
        CB                matlab.ui.control.Lamp
        CA                matlab.ui.control.Lamp
        BC                matlab.ui.control.Lamp
        BB                matlab.ui.control.Lamp
        BA                matlab.ui.control.Lamp
        AC                matlab.ui.control.Lamp
        AB                matlab.ui.control.Lamp
        AA                matlab.ui.control.Lamp
        Image             matlab.ui.control.Image
    end

    
    properties (Access = public)
        channelID
        readAPIKey
        Timer 
        HoraAA datetime
        HoraAB datetime
        HoraAC datetime
        HoraBA datetime
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            channelID = 3150731;
            readAPIKey = "41X5L7ZINFMZKVA1";
             
             
        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Callback function
        function PruebaButtonPushed(app, event)

        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)

    tarifaMin = 4000;   % COP por minuto

    % ============================
    %   LECTURA THINGSPEAK
    % ============================
    channelID = 3150731;
    readAPIKey = "41X5L7ZINFMZKVA1";

    SensorAA = thingSpeakRead(channelID, "Fields", 1, "ReadKey", readAPIKey);
    SensorAB = thingSpeakRead(channelID, "Fields", 2, "ReadKey", readAPIKey);
    SensorAC = thingSpeakRead(channelID, "Fields", 3, "ReadKey", readAPIKey);
    SensorBA = thingSpeakRead(channelID, "Fields", 4, "ReadKey", readAPIKey);

% ============================
%   AA
% ============================
if SensorAA == 1
    app.AA.Color = 'red';

    if isempty(app.HoraAA)
        app.HoraAA = datetime("now");   % guardar hora solo la primera vez
    end


    minutos = minutes(datetime("now") - app.HoraAA);
    app.LabelAA.Text = sprintf("$ %.0f", minutos * tarifaMin);

    app.Label2.Text = num2str(minutos);

else
    app.AA.Color = 'green';

    % Aquí NO usamos datetime con vacíos
    app.HoraAA = datetime.empty;       
    app.LabelAA.Text = "$ 0";
end


% ============================
%   AB
% ============================
if SensorAB == 1
    app.AB.Color = 'red';

    if isempty(app.HoraAB)
        app.HoraAB = datetime("now");   % guardar hora solo la primera vez
    end

    minutos = minutes(datetime("now") - app.HoraAB);
    app.LabelAB.Text = sprintf("$ %.0f", minutos * tarifaMin);

else
    app.AB.Color = 'green';

    % Aquí NO usamos datetime con vacíos
    app.HoraAB = datetime.empty;       
    app.LabelAB.Text = "$ 0";
end

% ============================
%   AC
% ============================
if SensorAC == 1
    app.AC.Color = 'red';

    if isempty(app.HoraAC)
        app.HoraAC = datetime("now");   % guardar hora solo la primera vez
    end

    minutos = minutes(datetime("now") - app.HoraAC);
    app.LabelAC.Text = sprintf("$ %.0f", minutos * tarifaMin);

else
    app.AC.Color = 'green';

    % Aquí NO usamos datetime con vacíos
    app.HoraAC = datetime.empty;       
    app.LabelAC.Text = "$ 0";
end

% ============================
%   BA
% ============================
if SensorBA == 1
    app.BA.Color = 'red';

    if isempty(app.HoraBA)
        app.HoraBA = datetime("now");   % guardar hora solo la primera vez
    end

    minutos = minutes(datetime("now") - app.HoraBA);
    app.LabelBA.Text = sprintf("$ %.0f", minutos * tarifaMin);

else
    app.BA.Color = 'green';

    % Aquí NO usamos datetime con vacíos
    app.HoraBA = datetime.empty;       
    app.LabelBA.Text = "$ 0";
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'smart (5).png');
            app.AA = uilamp(app.UIFigure);
            app.AB = uilamp(app.UIFigure);
            app.AC = uilamp(app.UIFigure);
            app.BA = uilamp(app.UIFigure);
            app.BB = uilamp(app.UIFigure);
            app.BC = uilamp(app.UIFigure);
            app.CA = uilamp(app.UIFigure);
            app.CB = uilamp(app.UIFigure);
            app.CC = uilamp(app.UIFigure);
            app.LabelAA = uilabel(app.UIFigure);
            app.ActualizarButton = uibutton(app.UIFigure, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.LabelAB = uilabel(app.UIFigure);
            app.LabelAC = uilabel(app.UIFigure);
            app.LabelBA = uilabel(app.UIFigure);
            app.AALabel = uilabel(app.UIFigure);
            app.AALabel.Text = 'AA';
            app.ABLabel = uilabel(app.UIFigure);
            app.ABLabel.Text = 'AB';
            app.ACLabel = uilabel(app.UIFigure);
            app.ACLabel.Text = 'AC';
            app.BALabel = uilabel(app.UIFigure);
            app.BALabel.Text = 'BA';
            app.BBLabel = uilabel(app.UIFigure);
            app.BBLabel.Text = 'BB';
            app.BCLabel = uilabel(app.UIFigure);
            app.BCLabel.Text = 'BC';
            app.BCLabel_2 = uilabel(app.UIFigure);
            app.BCLabel_2.Text = 'BC';
            app.BCLabel_3 = uilabel(app.UIFigure);
            app.BCLabel_3.Text = 'BC';
            app.BCLabel_4 = uilabel(app.UIFigure);
            app.BCLabel_4.Text = 'BC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end