classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        InfoImage                matlab.ui.control.Image
        Logo                     matlab.ui.control.Image
        SeleccioneDropDown       matlab.ui.control.DropDown
        SeleccioneDropDownLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ID)
        app.InfoImage.ImageSource = "C:\Users\julia\Downloads\fondo_transparente_png_by_imsnowbieber_d3jme6i-fullview.png";

        end

        % Value changed function: SeleccioneDropDown
        function SeleccioneDropDownValueChanged(app, event)
value = app.SeleccioneDropDown.Value;

switch value

    case 'Horario'
        app.InfoImage.ImageSource = "C:\Users\julia\Downloads\smart (1)\4.png";

    case 'Costos'
        app.InfoImage.ImageSource = "C:\Users\julia\Downloads\smart (1)\5.png";
end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SeleccioneDropDownLabel = uilabel(app.UIFigure);
            app.SeleccioneDropDownLabel.Text = 'Seleccione';
            app.SeleccioneDropDown = uidropdown(app.UIFigure);
            app.SeleccioneDropDown.Items = {'Horario', 'Costos'};
            app.SeleccioneDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccioneDropDownValueChanged, true);
            app.SeleccioneDropDown.Value = 'Costos';
            app.Logo = uiimage(app.UIFigure);
            app.Logo.ImageSource = fullfile(pathToMLAPP, 'smart (1).png');
            app.InfoImage = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end